classdef blackscholes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CallPutTab                      matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        PutpriceEditField               matlab.ui.control.NumericEditField
        PutpriceEditFieldLabel          matlab.ui.control.Label
        CallprceEditField               matlab.ui.control.NumericEditField
        CallprceEditFieldLabel          matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        TimetoexpirationEditField       matlab.ui.control.NumericEditField
        TimetoexpirationEditFieldLabel  matlab.ui.control.Label
        StockpriceEditField             matlab.ui.control.NumericEditField
        StockpriceLabel                 matlab.ui.control.Label
        StrikepriceEditField            matlab.ui.control.NumericEditField
        StrikepriceLabel                matlab.ui.control.Label
        BlackScholesModelLabel          matlab.ui.control.Label
        StockpredictionTab              matlab.ui.container.Tab
        BlackScholesModelLabel_2        matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        Image2_2                        matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        ErrorEditField                  matlab.ui.control.NumericEditField
        ErrorLabel                      matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ModelTab                        matlab.ui.container.Tab
        BlackScholesModelLabel_3        matlab.ui.control.Label
        Plot2Button                     matlab.ui.control.Button
        Panel_5                         matlab.ui.container.Panel
        Image2_3                        matlab.ui.control.Image
        Image_9                         matlab.ui.control.Image
        Image_8                         matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        UIAxes2                         matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %cu logo
            app.Image.ImageSource = 'C:\Users\bedo1\Desktop\logos\Cairo_University_Crest.png';
            app.Image_4.ImageSource = 'C:\Users\bedo1\Desktop\logos\Cairo_University_Crest.png';
            app.Image_7.ImageSource = 'C:\Users\bedo1\Desktop\logos\Cairo_University_Crest.png';
            
            %foe logo
            app.Image_2.ImageSource='C:\Users\bedo1\Desktop\logos\foe.png';
            app.Image_8.ImageSource='C:\Users\bedo1\Desktop\logos\foe.png';
            app.Image_5.ImageSource='C:\Users\bedo1\Desktop\logos\foe.png';
         
            %tccd logo
            app.Image_3.ImageSource='C:\Users\bedo1\Desktop\logos\tccd.png';
            app.Image_6.ImageSource='C:\Users\bedo1\Desktop\logos\tccd.png';
            app.Image_9.ImageSource='C:\Users\bedo1\Desktop\logos\tccd.png';
           
            %brownians logo
            app.Image2.ImageSource='C:\Users\bedo1\Desktop\logos\brownians.png';
            app.Image2_3.ImageSource='C:\Users\bedo1\Desktop\logos\brownians.png';
            app.Image2_2.ImageSource='C:\Users\bedo1\Desktop\logos\brownians.png';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)

            s=app.StockpriceEditField.Value;
            k=app.StrikepriceEditField.Value;
            t=app.TimetoexpirationEditField.Value;
            r=0.1325;
            v=0.1603;
            [c,p]=blsprice(s,k,r,t,v);
            app.CallprceEditField.Value=c;
            app.PutpriceEditField.Value=p;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed2(app, event)
%read dataset
egx=xlsread('C:\Users\bedo1\Desktop\jupyter\EGX30.xlsx');
%get the price column
egx_col=egx(:,4);

% get 6 months data from 13/2/2017 to 13/8/2017 
a=277;
b=395;
egx_stock = egx_col(a:b);

% get another 6 months data to compare
egx_compare=egx_col(b+1:2*b-a+1);

egx_calc=double.empty;
k=mean(egx_stock);
r=0.124;
t=0.5;
v=0.1603;
for i=1:length(egx_stock)
    egx_calc(i)=blsprice(egx_stock(i),k,r,t,v)+k
end

%calculate the error and plot
 app.ErrorEditField.Value=mean(abs(egx_compare-egx_calc')./egx_compare)*100;
 x=1:length(egx_calc);
 plot(app.UIAxes,x,egx_calc,x,egx_compare);
 legend(app.UIAxes,'Actual','Predicted');
 
        end

        % Button pushed function: Plot2Button
        function Plot2ButtonPushed(app, event)
            syms t d
S = sym(100);        % current stock price (spot price)
K = sym(95);         % exercise price (strike price)
sigma = sym(0.50);   % volatility of stock
T = sym(3/12);       % expiry time in years
r = sym(0.01);       % annualized risk-free interest rate
PV_K = K*exp(-r*T);
d1 = (log(S/K) + (r + sigma^2/2)*T)/(sigma*sqrt(T));
d2 = d1 - sigma*sqrt(T);
N(d) = int(exp(-((t)^2)/2),t,-Inf,d)*1/sqrt(2*sym(pi));
Csym = N(d1)*S - N(d2)*PV_K;

syms T S

PV_K = K*exp(-r*T);
d1 = (log(S/K) + (r + sigma^2/2)*T)/(sigma*sqrt(T));
d2 = d1 - sigma*sqrt(T);
Nd1 = int(exp(-((t)^2)/2),-Inf,d1)*1/sqrt(2*pi);
Nd2 = int(exp(-((t)^2)/2),-Inf,d2)*1/sqrt(2*pi);
C = Nd1*S - Nd2*PV_K;
fsurf(app.UIAxes2,C,[50 140 0 0.25])

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CallPutTab = uitab(app.TabGroup);
            app.CallPutTab.Title = 'Call / Put';
            app.BlackScholesModelLabel = uilabel(app.CallPutTab);
            app.BlackScholesModelLabel.Text = 'Black Scholes Model';
            app.StrikepriceLabel = uilabel(app.CallPutTab);
            app.StrikepriceLabel.Text = '* Strike price';
            app.StrikepriceEditField = uieditfield(app.CallPutTab, 'numeric');
            app.StockpriceLabel = uilabel(app.CallPutTab);
            app.StockpriceLabel.Text = '* Stock price';
            app.StockpriceEditField = uieditfield(app.CallPutTab, 'numeric');
            app.TimetoexpirationEditFieldLabel = uilabel(app.CallPutTab);
            app.TimetoexpirationEditFieldLabel.Text = '* Time to expiration';
            app.TimetoexpirationEditField = uieditfield(app.CallPutTab, 'numeric');
            app.CalculateButton = uibutton(app.CallPutTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CallprceEditFieldLabel = uilabel(app.CallPutTab);
            app.CallprceEditFieldLabel.Text = 'Call prce';
            app.CallprceEditField = uieditfield(app.CallPutTab, 'numeric');
            app.PutpriceEditFieldLabel = uilabel(app.CallPutTab);
            app.PutpriceEditFieldLabel.Text = 'Put price';
            app.PutpriceEditField = uieditfield(app.CallPutTab, 'numeric');
            app.Panel_3 = uipanel(app.CallPutTab);
            app.Image = uiimage(app.Panel_3);
            app.Image_2 = uiimage(app.Panel_3);
            app.Image_3 = uiimage(app.Panel_3);
            app.Image2 = uiimage(app.Panel_3);
            app.StockpredictionTab = uitab(app.TabGroup);
            app.StockpredictionTab.Title = 'Stock prediction';
            app.UIAxes = uiaxes(app.StockpredictionTab);
            title(app.UIAxes, 'Actual vs predicted ')
            xlabel(app.UIAxes, 'Time (days)')
            ylabel(app.UIAxes, 'Price (in EGP)')
            zlabel(app.UIAxes, 'Z')
            app.ErrorLabel = uilabel(app.StockpredictionTab);
            app.ErrorLabel.Text = 'Error %';
            app.ErrorEditField = uieditfield(app.StockpredictionTab, 'numeric');
            app.Panel_4 = uipanel(app.StockpredictionTab);
            app.Image_4 = uiimage(app.Panel_4);
            app.Image_5 = uiimage(app.Panel_4);
            app.Image_6 = uiimage(app.Panel_4);
            app.Image2_2 = uiimage(app.Panel_4);
            app.PlotButton = uibutton(app.StockpredictionTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed2, true);
            app.PlotButton.Text = 'Plot';
            app.BlackScholesModelLabel_2 = uilabel(app.StockpredictionTab);
            app.BlackScholesModelLabel_2.Text = 'Black Scholes Model';
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.UIAxes2 = uiaxes(app.ModelTab);
            title(app.UIAxes2, 'Black scholes model')
            xlabel(app.UIAxes2, 'Strike price')
            ylabel(app.UIAxes2, 'Time to expiration')
            zlabel(app.UIAxes2, 'Call price')
            app.Panel_5 = uipanel(app.ModelTab);
            app.Image_7 = uiimage(app.Panel_5);
            app.Image_8 = uiimage(app.Panel_5);
            app.Image_9 = uiimage(app.Panel_5);
            app.Image2_3 = uiimage(app.Panel_5);
            app.Plot2Button = uibutton(app.ModelTab, 'push');
            app.Plot2Button.ButtonPushedFcn = createCallbackFcn(app, @Plot2ButtonPushed, true);
            app.Plot2Button.Text = 'Plot2';
            app.BlackScholesModelLabel_3 = uilabel(app.ModelTab);
            app.BlackScholesModelLabel_3.Text = 'Black Scholes Model';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blackscholes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end